classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InstructionLabel         matlab.ui.control.Label
        ButtonPanel              matlab.ui.container.Panel
        AddPolygonButton         matlab.ui.control.Button
        DoneButton               matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        ImagePanel               matlab.ui.container.Panel
        AddImageButton           matlab.ui.control.Button
        ImageBox                 matlab.ui.control.UIAxes
        ScreenNameLabel          matlab.ui.control.Label
        TourIntoThePictureLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Image % Image to process
        Screen % Store the state of the app
        ROI = {} % Region of interest
        NumROI = 0; % Index of region of interest
    end
    
    methods (Access = private)
        
        function SetScreen(app)
            switch app.Screen
                case Screens.AddImage
                    app.ActivateAddImageScreen()

                case Screens.ChooseForeground
                    app.ActivateChooseForegroundScreen()
            end
            
        end
        
        function ActivateAddImageScreen(app)
            cla(app.ImageBox);
            app.NumROI = 0;
            app.ScreenNameLabel.Text = 'Add Image';
            app.InstructionLabel.Text = 'Add an image by clicking';
            app.AddImageButton.Visible = 1;
            app.AddImageIconImage.Visible = 1;            
            app.BackButton.Visible = 0;
            app.AddPolygonButton.Visible = 0;
            app.DoneButton.Visible = 0;
        end

        function ActivateChooseForegroundScreen(app) 
            app.ScreenNameLabel.Text = 'Select foreground objects';
            app.InstructionLabel.Text = ['Select the foreground objects in the image ' ...
                'by drawing a region of interest around each one of them'];            
            app.AddImageButton.Visible = 0;
            app.BackButton.Visible = 1;
            app.BackButton.Tooltip = "Back to add image";
            app.DoneButton.Visible = 1;   
            app.AddPolygonButton.Visible = 1;                     
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Screen = Screens.AddImage;
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            
            % So that user can still choose image in fullscreen
            app.UIFigure.WindowStyle = "normal";

            [file, location, indx] = uigetfile({'*.jpg;*.png;*.bmp', 'Images (*.jpg, *.png, *.bmp)'});

             app.UIFigure.WindowStyle = "alwaysontop";

            if isequal(file,0)
                disp('User selected Cancel')
                return
            else
                disp(['User selected ', fullfile(location,file),...
                    ' and filter index: ', num2str(indx)])
            end

            app.Image = imread(fullfile(location, file));

            [sz1, sz2] = size(app.Image);

            % Image size reduction, faster image processing
            if any(sz1>3000 | sz2>3000)
                reduce_scal = 3200/max(sz1,sz2);
                app.Image = imresize(app.Image, reduce_scal,"Antialiasing",false);
            end            

            cla(app.ImageBox,'reset')
            imshow(app.Image, 'Parent', app.ImageBox)

            app.Screen = Screens.ChooseForeground;
            app.SetScreen();
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.Screen = Screens(app.Screen - 1); 
            app.SetScreen();    
        end

        % Button pushed function: AddPolygonButton
        function AddPolygonButtonButtonPushed(app, event)
            app.NumROI = app.NumROI + 1;  
            app.ROI{app.NumROI} = drawpolygon(app.ImageBox);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            app.AddPolygonButton.Visible = 0;
            app.DoneButton.Visible = 0;

            background = app.Image;
            foreground = cell(1,app.NumROI);
            fg2D = cell(1,app.NumROI);

            % Parameters for function inpaintExemplar
            patchsize = 9;
            fillorder = 'gradient'; 

            for i = 1:app.NumROI
                fg2D{i} = app.ROI{i}.Position';            
            end

            app.UIFigure.Pointer = "watch"; % Change mouse pointer shape
            app.UIFigure.WindowStyle = "normal";
            app.BackButton.Enable = 0;
            d = waitbar(0.2,'Rendering image ...'); % Open up waitbar  
            disableDefaultInteractivity(app.ImageBox)            

            for i = 1:app.NumROI
                % separate the foreground mask and background picture               
                foregroundmask = createMask(app.ROI{i});
                foreground{i} = bsxfun(@times,app.Image, cast(foregroundmask, 'like', app.Image));
                background = inpaintExemplar(background, foregroundmask, "PatchSize", patchsize, "FillOrder", fillorder);             
                waitbar(i/app.NumROI,d,'Rendering image ...');
            end


            close(d) % Close waitbar
            
            app.UIFigure.Pointer = "arrow"; % Change mouse pointer shape
            app.UIFigure.WindowStyle = "alwaysontop";
            enableDefaultInteractivity(app.ImageBox)
            app.BackButton.Enable = 1;
            % 
            assignin("base","foreground",foreground)
            assignin("base","background",background)
            assignin("base","fg2D",fg2D)
            cla(app.ImageBox,'reset')
            imshow(background, 'Parent', app.ImageBox)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TourIntoThePictureLabel = uilabel(app.UIFigure);
            app.TourIntoThePictureLabel.Text = 'Tour Into The Picture';
            app.ScreenNameLabel = uilabel(app.UIFigure);
            app.ScreenNameLabel.Text = 'Add picture ';
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImageBox = uiaxes(app.ImagePanel);
            app.ImageBox.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImageBox.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ImageBox.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImageBox.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ImageBox.Visible = 'off';
            app.AddImageButton = uibutton(app.ImagePanel, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'select-picture.png');
            app.AddImageButton.Tooltip = {'Add image'};
            app.AddImageButton.Text = '';
            app.ButtonPanel = uipanel(app.UIFigure);
            app.BackButton = uibutton(app.ButtonPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'icons', 'back.png');
            app.BackButton.Visible = 'off';
            app.BackButton.Tooltip = {'Back'};
            app.BackButton.Text = '';
            app.DoneButton = uibutton(app.ButtonPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Icon = fullfile(pathToMLAPP, 'icons', 'done.png');
            app.DoneButton.Visible = 'off';
            app.DoneButton.Tooltip = {'Finished'};
            app.DoneButton.Text = '';
            app.AddPolygonButton = uibutton(app.ButtonPanel, 'push');
            app.AddPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @AddPolygonButtonButtonPushed, true);
            app.AddPolygonButton.Icon = fullfile(pathToMLAPP, 'icons', 'AddPolygon.png');
            app.AddPolygonButton.Visible = 'off';
            app.AddPolygonButton.Tooltip = {'Add polygon'};
            app.AddPolygonButton.Text = '';
            app.InstructionLabel = uilabel(app.UIFigure);
            app.InstructionLabel.Text = 'Add an image by clicking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end